classdef tresholdingKeraTraces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        EditchannelDropDownLabel       matlab.ui.control.Label
        EditchannelDropDown            matlab.ui.control.DropDown
        SnapallregionswithcenterLabel  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        EditField                      matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        andLabel                       matlab.ui.control.Label
        TostateLabel                   matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        SetButton                      matlab.ui.control.Button
        ByDropDownLabel                matlab.ui.control.Label
        ByDropDown                     matlab.ui.control.DropDown
        valueLabel                     matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
        AndcurrentlyatstateLabel       matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        histVal % Input argument containing the heights of the histogram
        edgeVal % Input argument containing the edges of the histogram bins
        channels %Input argument containing the number of channels
        channel % The channel currently selected to edit
        method  % The method currently selected: 1 to compare the absolute
                % value of the traces, 2 to normalize each one and use the 
                % relative values within each trace
        threshold %the threshold value(s) eventually chosen
        boundDirection % 1 if "above", 2 if "below", 3 if "between"
        stateSet %Integer, state which the 
        maxStates
        state2ChangeFrom
    end
    
    methods (Access = private)
        function updateHistogram(app)
            hold(app.UIAxes,'off');
            for i0 = 1:app.maxStates(app.channel)
                if any(app.histVal{app.channel,i0,app.method}~=0)
                histogram(app.UIAxes,'BinEdges',app.edgeVal{app.channel,i0,app.method},'BinCounts',app.histVal{app.channel,i0,app.method},'LineStyle','none');
                hold(app.UIAxes,'on');
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, histVal, edgeVal, channels, maxStates)
            app.histVal = histVal;
            app.edgeVal = edgeVal;
            app.channels = channels;
            app.maxStates = maxStates;
            ddOpt = cell([1 channels]);
            dd2Opt = cell([1 maxStates(1)]);
            for j = 1:channels
                ddOpt{j} = num2str(j);
            end
            for i0 = 1:maxStates(1)
                dd2Opt{i0} = num2str(i0);
            end
            dd2Opt = cat(2,{'Any'},dd2Opt);
            app.EditchannelDropDown.Items = ddOpt;
            app.EditchannelDropDown.ItemsData = 1:channels;
            app.DropDown_2.Items = dd2Opt;
            app.DropDown_2.ItemsData = 0:maxStates(1);
            app.DropDown.ItemsData = [ 1 2 3 ];
            app.ByDropDown.ItemsData = [ 1 2 ];
            app.stateSet = 0;
            app.threshold = 0;
            
            app.EditField_2.Enable = 'off';
            app.andLabel.Text = '';
            app.method = app.ByDropDown.Value;
            app.channel = app.EditchannelDropDown.Value;
            app.boundDirection = 1;
            app.state2ChangeFrom = 0;
            updateHistogram(app);
        end

        % Value changed function: EditchannelDropDown
        function EditchannelDropDownValueChanged(app, event)
            value = app.EditchannelDropDown.Value;
            app.channel = value;
            updateHistogram(app);
            dd2Opt = cell([1 app.maxStates(app.channel)]);
            for i0 = 1:app.maxStates(value)
                dd2Opt{i0} = num2str(i0);
            end
            dd2Opt = cat(2,{'Any'},dd2Opt);
            app.DropDown_2.Items = dd2Opt;
            app.DropDown_2.ItemsData = 0:app.maxStates(value);
            
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value == 3
                app.EditField_2.Enable = 'on';
                app.andLabel.Text = 'and';
            else
                app.EditField_2.Enable = 'off';
                app.andLabel.Text = '';
            end
            app.boundDirection = value;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.threshold(1) = value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.threshold(2) = value;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.stateSet = value;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
%             evalin('caller','assignin("caller","stateSet",app.stateSet)');
            value = app.EditField_3.Value;
            app.stateSet = value;
            value = app.EditField_2.Value;
            app.threshold(2) = value;
            value = app.EditField.Value;
            app.threshold(1) = value;
            value = app.DropDown_2.Value;
            app.state2ChangeFrom = value;
            pause(1);
            close(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app.UIFigure)
            
        end

        % Value changed function: ByDropDown
        function ByDropDownValueChanged(app, event)
            value = app.ByDropDown.Value;
            app.method = value;
            updateHistogram(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.threshold = NaN;
            pause(1);
            close(app.UIFigure);
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            app.state2ChangeFrom = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EditchannelDropDownLabel = uilabel(app.LeftPanel);
            app.EditchannelDropDownLabel.Text = 'Edit channel';
            app.EditchannelDropDown = uidropdown(app.LeftPanel);
            app.EditchannelDropDown.ValueChangedFcn = createCallbackFcn(app, @EditchannelDropDownValueChanged, true);
            app.SnapallregionswithcenterLabel = uilabel(app.LeftPanel);
            app.SnapallregionswithcenterLabel.Text = 'Snap all regions with center';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'Above', 'Below', 'Between'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Above';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 1;
            app.andLabel = uilabel(app.LeftPanel);
            app.andLabel.Text = 'and';
            app.TostateLabel = uilabel(app.LeftPanel);
            app.TostateLabel.Text = 'To state';
            app.EditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.SetButton = uibutton(app.LeftPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.ByDropDownLabel = uilabel(app.LeftPanel);
            app.ByDropDownLabel.Text = 'By';
            app.ByDropDown = uidropdown(app.LeftPanel);
            app.ByDropDown.Items = {'Absolute', 'Relative'};
            app.ByDropDown.ValueChangedFcn = createCallbackFcn(app, @ByDropDownValueChanged, true);
            app.ByDropDown.Value = 'Absolute';
            app.valueLabel = uilabel(app.LeftPanel);
            app.valueLabel.Text = 'value';
            app.DropDown_2 = uidropdown(app.LeftPanel);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.AndcurrentlyatstateLabel = uilabel(app.LeftPanel);
            app.AndcurrentlyatstateLabel.Text = 'And currently at state';
            app.CancelButton = uibutton(app.LeftPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Set distribution')
            xlabel(app.UIAxes, 'Bin center')
            ylabel(app.UIAxes, 'Counts')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tresholdingKeraTraces(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end